classdef VIONWAKE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lamp                           matlab.ui.control.Lamp
        RadialConfinementSpinner       matlab.ui.control.Spinner
        RadialConfinementSpinnerLabel  matlab.ui.control.Label
        VerticalConfinementSpinner     matlab.ui.control.Spinner
        VerticalConfinementSpinnerLabel  matlab.ui.control.Label
        DragSpinner                    matlab.ui.control.Spinner
        DragSpinnerLabel               matlab.ui.control.Label
        GravitySpinner                 matlab.ui.control.Spinner
        GravitySpinnerLabel            matlab.ui.control.Label
        PlayButton                     matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Simulation parameters
        BLOCK                   = 32;
        GAS_TEMPERATURE         = 288.15;
        GAS_PRESSURE            = 2;
        ION_MASS                = 6.68e-26;
        ELECTRON_CHARGE         = -1.60e-19;
        LENGTH_DEBYE            = 90e-6;
        SHEATH_HEIGHT           = 10.6e-3;
        WAKE_CHARGE_PERCENT     = 0.0;
        WAKE_LENGTH             = 45e-6;
        CUTOFF_MULTIPLIER       = 6;
        
        NUM_PARTICLES           = 500;
        DUST_DENSITY            = 1.51 * 1e3;
        DUST_DIAMETER_MEAN      = 8.89e-6;
        DUST_RADIUS_MEAN
        DUST_DIAMETER_STD       = 0.01e-6;
        DUST_CHARGE_DENSITY_MEAN= 1000 * 1e6;
        DUST_CHARGE_DENSITY_STD = 20 * 1e6;
        
        COULOMB                 = 8.98e12;
        BOLTZMANN               = 1.38e-23;
        GRAVITY                 = 9.8;
        CELL_CHARGE             = 1.6e2;
        CELL_RADIUS             = 0.6e-2;
        CELL_HEIGHT             = 1.2e-2;
        E_0                     = 3350;
        
        TIME_STEP               = 0.005;
        RATE_DRAW               = 20;
        RATE_PRINT              = 200;
        
        DT                      = 0.001;
        
        TOTAL_TIME              = 0;

        % Simulation variables
        dust;
        wake;
        plasma; 

        % Callbacks
        play                   = false;

        % Cuda kernel objects
        pcryAcc;
        pcryPos;

        % Axes objects
        dustAxes;
    end
    
    methods (Access = private)
        function drawCell(app)
            % Set axis limits
            app.UIAxes.XLim = [-app.CELL_RADIUS app.CELL_RADIUS];
            app.UIAxes.YLim = [-app.CELL_RADIUS app.CELL_RADIUS];
            app.UIAxes.ZLim = [0 app.CELL_HEIGHT];
            
            % Draw cell electrodes and sheath
            theta = linspace(0,2*pi,30);

            hold(app.UIAxes,'on')
            plot3(app.UIAxes,app.CELL_RADIUS * cos(theta),...
                            app.CELL_RADIUS * sin(theta),...
                            zeros(numel(theta),1),'k');

            plot3(app.UIAxes,app.CELL_RADIUS * cos(theta),...
                            app.CELL_RADIUS * sin(theta),...
                            repmat(app.CELL_HEIGHT,[numel(theta) 1]),'k');

            plot3(app.UIAxes,app.CELL_RADIUS * cos(theta),...
                            app.CELL_RADIUS * sin(theta),...
                            repmat(app.SHEATH_HEIGHT,[numel(theta) 1]),'m');

            hold(app.UIAxes,'off')

            view(app.UIAxes,3)
        end

        function drawDust(app)
            [x,y,z] = gather(app.dust.x,app.dust.y,app.dust.z);

            hold(app.UIAxes,'on')
            app.dustAxes = scatter3(app.UIAxes,...
                                   x,...
                                   y,...
                                   z,20,'.k');
            hold(app.UIAxes,'off')
        end

        function setCudaKernels(app)
            ThreadBlockSize = app.BLOCK;
            GridSize        = floor((app.NUM_PARTICLES-1)/ThreadBlockSize) + 1;
            
            ! nvcc -ptx pcry.cu
            acc = parallel.gpu.CUDAKernel('pcry.ptx','pcry.cu','pcryCalculate_ACC');
            pos = parallel.gpu.CUDAKernel('pcry.ptx','pcry.cu','pcryCalculate_POS');
            
            acc.GridSize        = [GridSize 1 1];
            pos.GridSize        = [GridSize 1 1];
            acc.ThreadBlockSize = [ThreadBlockSize 1 1];
            pos.ThreadBlockSize = [ThreadBlockSize 1 1];

            app.pcryAcc = acc;
            app.pcryPos = pos;
        end

        function generateParticles(app)
            % Generate particle positions
            phi = rand(app.NUM_PARTICLES,1,'single') * (2*pi);
            r   = rand(app.NUM_PARTICLES,1,'single') * (app.CELL_RADIUS);
            z   = rand(app.NUM_PARTICLES,1,'single') * (app.CELL_HEIGHT * 0.1) + app.CELL_HEIGHT * (1 - 0.1);

            x   = r .* cos(phi);
            y   = r .* sin(phi);

            % Generate particle positions and velocities
            vx = zeros(app.NUM_PARTICLES,1,'single');
            vy = zeros(app.NUM_PARTICLES,1,'single');
            vz = zeros(app.NUM_PARTICLES,1,'single');
            
            ax = zeros(app.NUM_PARTICLES,1,'single');
            ay = zeros(app.NUM_PARTICLES,1,'single');
            az = zeros(app.NUM_PARTICLES,1,'single');
            
            % Generate particle sizes, masses and charges
            diameter = (randn(app.NUM_PARTICLES,1,'single') * app.DUST_DIAMETER_STD + app.DUST_DIAMETER_MEAN);
            radius   = diameter / 2;
            mass     = app.DUST_DENSITY * (4/3 * pi * radius.^3);
            charge   = round(app.DUST_CHARGE_DENSITY_MEAN * diameter) * app.ELECTRON_CHARGE;

            % Generate wake attributes
            wake_charge_p           = app.WAKE_CHARGE_PERCENT * ones(app.NUM_PARTICLES,1,'single');
            wake_length             = app.WAKE_LENGTH * ones(app.NUM_PARTICLES,1,'single');
            wake_nn_r               = 1000 * app.LENGTH_DEBYE * ones(app.NUM_PARTICLES,1,'single');
            wake_nn_z               = 1000 * app.LENGTH_DEBYE * ones(app.NUM_PARTICLES,1,'single');
            wake_nn_id              = -1 * ones(app.NUM_PARTICLES,1,'int32');
        
            % Save variables
            app.dust.x              = gpuArray(x);
            app.dust.y              = gpuArray(y);
            app.dust.z              = gpuArray(z);
            app.dust.vx             = gpuArray(vx);
            app.dust.vy             = gpuArray(vy);
            app.dust.vz             = gpuArray(vz);
            app.dust.ax             = gpuArray(ax);
            app.dust.ay             = gpuArray(ay);
            app.dust.az             = gpuArray(az);
            app.dust.diameter       = gpuArray(diameter);
            app.dust.radius         = gpuArray(radius);
            app.dust.charge         = gpuArray(charge);
            app.dust.mass           = gpuArray(mass);
            
            app.wake.charge         = gpuArray(wake_charge_p);
            app.wake.length         = gpuArray(wake_length);
            app.wake.nn_r           = gpuArray(wake_nn_r);
            app.wake.nn_z           = gpuArray(wake_nn_z);
            app.wake.nn_id          = gpuArray(wake_nn_id);
        end

        function calculatePlasmaParams(app)
            app.plasma.BETA = 30;

            app.DUST_RADIUS_MEAN = app.DUST_DIAMETER_MEAN / 2;
        end

        function calculateTimeStep(app)
            % CALCULATE THE FORCES
            [app.dust.x,app.dust.y,app.dust.z,...
             app.dust.vx,app.dust.vy,app.dust.vz,...
             app.dust.ax,app.dust.ay,app.dust.az,...
             app.dust.radius,...
             app.dust.mass,...
             app.dust.charge,...
             app.wake.charge,...
             app.wake.length,...
             app.wake.nn_z,...
             app.wake.nn_r,...
             app.wake.nn_id] = feval(app.pcryAcc,...
                                app.dust.x,app.dust.y,app.dust.z,...
                                app.dust.vx,app.dust.vy,app.dust.vz,...
                                app.dust.ax,app.dust.ay,app.dust.az,...
                                app.dust.radius,...
                                app.dust.mass,...
                                app.dust.charge,...
                                app.wake.charge,...
                                app.wake.length,...
                                app.wake.nn_z,...
                                app.wake.nn_r,...
                                app.wake.nn_id,...
                                app.DUST_RADIUS_MEAN,...
                                app.COULOMB,...
                                app.LENGTH_DEBYE,...
                                app.CUTOFF_MULTIPLIER,...
                                app.CELL_RADIUS,...
                                app.CELL_CHARGE,...
                                app.CELL_HEIGHT,...
                                app.SHEATH_HEIGHT,...
                                app.WAKE_CHARGE_PERCENT,...
                                app.GRAVITY,...
                                app.plasma.BETA,...
                                app.NUM_PARTICLES);
%             z = gather(app.dust.z);
%             charge = gather(app.dust.charge);
%             mass = gather(app.dust.mass);
% 
%             E = -8083 + 553373*z + 2.0e8*z.^2 -3.017e10*z.^3 + 1.471e12*z.^4 - 2.306e13*z.^5;
%             app.dust.az = gpuArray((charge .* E ./ mass) - app.GRAVITY);

            % CALCULATE POSITIONS


            fprintf("Charge: %e ",gather(app.dust.charge(1)));
            fprintf("Position: %e ",gather(app.dust.z(1)));
            fprintf("Acc: %e \n",gather(app.dust.az(1)));

            [app.dust.x,app.dust.y,app.dust.z,...
             app.dust.vx,app.dust.vy,app.dust.vz,...
             app.dust.ax,app.dust.ay,app.dust.az,...
             app.dust.radius,...
             app.dust.mass,...
             app.dust.charge,...
             app.wake.charge,...
             app.wake.length,...
             app.wake.nn_z,...
             app.wake.nn_r,...
             app.wake.nn_id] = feval(app.pcryPos, ...
                                app.dust.x,app.dust.y,app.dust.z,...
                                app.dust.vx,app.dust.vy,app.dust.vz,...
                                app.dust.ax,app.dust.ay,app.dust.az,...
                                app.dust.radius,...
                                app.dust.mass,...
                                app.dust.charge,...
                                app.wake.charge,...
                                app.wake.length,...
                                app.wake.nn_z,...
                                app.wake.nn_r,...
                                app.wake.nn_id,...
                                app.DUST_CHARGE_DENSITY_MEAN,...
                                app.ELECTRON_CHARGE,...
                                app.CUTOFF_MULTIPLIER,...
                                app.WAKE_CHARGE_PERCENT,...
                                app.WAKE_LENGTH,...
                                app.LENGTH_DEBYE,...
                                app.DT,...
                                app.TOTAL_TIME,...
                                app.NUM_PARTICLES);

            fprintf("Charge: %e ",gather(app.dust.charge(1)));
            fprintf("Position: %e ",gather(app.dust.z(1)));
            fprintf("Acc: %e \n",gather(app.dust.az(1)));
        end

        function formatCallbacks(app)
            % GRAVITY SPINNER
            app.GravitySpinner.Value = app.GRAVITY;
            app.GravitySpinner.Step = 0.1;
            app.GravitySpinner.Limits = [0 100];

            % DRAG SPINNER
            app.DragSpinner.Value = app.plasma.BETA;
            app.DragSpinner.Step = 0.01;
            app.DragSpinner.Limits = [0 10000];

            % LAMP
            app.Lamp.Color = 'red';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setCudaKernels(app);
            generateParticles(app);
            calculatePlasmaParams(app);
            formatCallbacks(app)
            drawCell(app);
            drawDust(app);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            app.play = app.PlayButton.Value;
            i = 0;
            
            % Change the color of the lamp
            if app.play == true
                app.Lamp.Color = 'green';
            else
                app.Lamp.Color = 'red';
            end

            % Keep the simulation running
            while app.play == true
                calculateTimeStep(app)
                
                app.TOTAL_TIME = app.TOTAL_TIME + (i * app.TIME_STEP);
                
                app.dustAxes.XData = gather(app.dust.x);
                app.dustAxes.YData = gather(app.dust.y);
                app.dustAxes.ZData = gather(app.dust.z);


                drawnow limitrate

                i = i + 1;
            end
        end

        % Value changing function: GravitySpinner
        function GravitySpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.GRAVITY = changingValue;
        end

        % Value changing function: DragSpinner
        function DragSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.plasma.BETA = changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.GravitySpinnerLabel = uilabel(app.UIFigure);
            app.GravitySpinnerLabel.Text = 'Gravity';
            app.GravitySpinner = uispinner(app.UIFigure);
            app.GravitySpinner.ValueChangingFcn = createCallbackFcn(app, @GravitySpinnerValueChanging, true);
            app.DragSpinnerLabel = uilabel(app.UIFigure);
            app.DragSpinnerLabel.Text = 'Drag';
            app.DragSpinner = uispinner(app.UIFigure);
            app.DragSpinner.ValueChangingFcn = createCallbackFcn(app, @DragSpinnerValueChanging, true);
            app.VerticalConfinementSpinnerLabel = uilabel(app.UIFigure);
            app.VerticalConfinementSpinnerLabel.Text = 'Vertical Confinement';
            app.VerticalConfinementSpinner = uispinner(app.UIFigure);
            app.RadialConfinementSpinnerLabel = uilabel(app.UIFigure);
            app.RadialConfinementSpinnerLabel.Text = 'Radial Confinement';
            app.RadialConfinementSpinner = uispinner(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VIONWAKE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end